classdef revisedRTAppv2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        FinishedPanel             matlab.ui.container.Panel
        FinishedLabel             matlab.ui.control.Label
        MainPanel                 matlab.ui.container.Panel
        mainLampLabel             matlab.ui.control.Label
        Lamp                      matlab.ui.control.Lamp
        InstructionsButton        matlab.ui.control.Button
        NextButton                matlab.ui.control.Button
        Q5Slider                  matlab.ui.control.Slider
        Q5Label                   matlab.ui.control.Label
        Q4Slider                  matlab.ui.control.Slider
        Q4Label                   matlab.ui.control.Label
        Q3Slider                  matlab.ui.control.Slider
        Q3Label                   matlab.ui.control.Label
        Q2Slider                  matlab.ui.control.Slider
        Q2Label                   matlab.ui.control.Label
        Q1Slider                  matlab.ui.control.Slider
        Q1Label                   matlab.ui.control.Label
        PracticePanel             matlab.ui.container.Panel
        practiceLampLabel         matlab.ui.control.Label
        practiceLamp              matlab.ui.control.Lamp
        practiceReadyButton       matlab.ui.control.Button
        practiceLabel             matlab.ui.control.Label
        ConceptsPanel             matlab.ui.container.Panel
        ReadyButton               matlab.ui.control.Button
        Q5ConceptHelpButton       matlab.ui.control.Button
        Q4ConceptHelpButton       matlab.ui.control.Button
        Q3ConceptHelpButton       matlab.ui.control.Button
        Q2ConceptHelpButton       matlab.ui.control.Button
        Q1ConceptHelpButton       matlab.ui.control.Button
        Q5ConceptLabel            matlab.ui.control.Label
        Q4ConceptLabel            matlab.ui.control.Label
        Q3ConceptLabel            matlab.ui.control.Label
        Q2ConceptLabel            matlab.ui.control.Label
        Q1ConceptLabel            matlab.ui.control.Label
        ConceptLabel              matlab.ui.control.Label
        PersonalInformationPanel  matlab.ui.container.Panel
        AgeEditField              matlab.ui.control.NumericEditField
        AgeLabel                  matlab.ui.control.Label
        InfoReadyButton           matlab.ui.control.Button
        ExperienceHelpButton      matlab.ui.control.Button
        InstructionsHelpButton    matlab.ui.control.Button
        InstructionsLabel         matlab.ui.control.Label
        CommentEditField          matlab.ui.control.EditField
        ParticipantIDLabel_6      matlab.ui.control.Label
        EthnicityEditField        matlab.ui.control.EditField
        ParticipantIDLabel_5      matlab.ui.control.Label
        GenderEditField           matlab.ui.control.EditField
        ParticipantIDLabel_4      matlab.ui.control.Label
        NameEditField             matlab.ui.control.EditField
        ParticipantIDLabel_2      matlab.ui.control.Label
        IDEditField               matlab.ui.control.EditField
        ParticipantIDLabel        matlab.ui.control.Label
        CommentHelpButton         matlab.ui.control.Button
        SingingExperienceLabel    matlab.ui.control.Label
        ProfessionalCheckBox      matlab.ui.control.CheckBox
        SemiProfessionalCheckBox  matlab.ui.control.CheckBox
        AmateurCheckBox           matlab.ui.control.CheckBox
        NoviceCheckBox            matlab.ui.control.CheckBox
        EthnicityHelpButton       matlab.ui.control.Button
        GenderHelpButton          matlab.ui.control.Button
        AgeHelpButton             matlab.ui.control.Button
        NameHelpButton            matlab.ui.control.Button
        IDHelpButton              matlab.ui.control.Button
        WelcomePanel              matlab.ui.container.Panel
        BeginButton               matlab.ui.control.Button
        IntroLabel                matlab.ui.control.Label
    end

    
    properties (Access = private)
        % Variables for handling personal data----------------------------
        % Variables to hold personal data in
        id = {'null'};
        name = {'null'};
        age = 0;
        gender = {'null'};
        ethnicity = {'null'};
        experience = {'null'};
        expComment = {'null'};

        % Variables for checking personal data participant input state
        idCheck = 0;
        nameCheck = 0;
        ageCheck = 0;
        genderCheck = 0;
        ethnicityCheck = 0;
        experienceCheck = 0;

        % Variables for IP networking-------------------------------------
        % Add Reaper OSC Tx socket
        reaperOSC = udp('127.0.0.1', 8000); % Define reaper device ipv4 here

        % Declare ports and variables for UDP session control comms
        commsRx = udpport("LocalPort", 8040); % Receive Comms on 127.0.0.1:8040
        commsTx = udpport("LocalPort", 8041); % Send Comms from 127.0.0.1:8041
        destIP = "127.0.0.1"; % Use local host for testing - add an update from worksheet in startupFcn later
        destPort = 8040;
        readyStatus = 0; % Boolean to tell whether partner is ready or not

        % Variables for Likert response-----------------------------------
        q1 = zeros(27, 1); % Variable to hold responses to likert item 1
        q2 = zeros(27, 1); % " 2
        q3 = zeros(27, 1); % " 3
        q4 = zeros(27, 1); % " 4
        q5 = zeros(27, 1); % " 5

        % variables to verify slider value changed
        q1changed = 0;
        q2changed = 0;
        q3changed = 0;
        q4changed = 0;
        q5changed = 0;

        % Variables for holding the test sequence-------------------------
        repSeq = 0; % Null for now, these get filled in on startup
        delSeq = 0;
        sysSeq = 0;

        % A variable for incrementing through the test sequence
        index = 0;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Set window size----------------------------------------------
            app.UIFigure.WindowState = 'maximized'; % Set to fullscreen
            
            % Sort panel visibility----------------------------------------
            app.WelcomePanel.Visible = 'on'; % Welcome panel on
            app.PersonalInformationPanel.Visible = 'off'; % Personal info panel off
            app.ConceptsPanel.Visible = 'off'; % Concept info panel off
            app.PracticePanel.Visible = 'off'; % Practice panel off
            app.MainPanel.Visible = 'off'; % Main panel off
            app.FinishedPanel.Visible = 'off'; % Finished panel off

            % Clean our ports---------------------------------------------
            flush(app.commsRx);
            flush(app.commsTx);

            % Load the test sequence--------------------------------------
            sequence = readtable("testSequence.xlsx");
            app.repSeq = sequence.Repetition;
            app.delSeq = sequence.Delay;
            app.sysSeq = sequence.System;

            % Update IP from spreadsheet
            tempIP = readtable("IPs.xlsx");
            app.destIP = tempIP.Destination{1};

            % Update likert questions from spreadsheet
            likertItems = readtable("likertItems.xlsx");
            likertItems = likertItems.LikertItems;

            app.Q1Label.Text = likertItems{1};
            app.Q2Label.Text = likertItems{2};
            app.Q3Label.Text = likertItems{3};
            app.Q4Label.Text = likertItems{4};
            app.Q5Label.Text = likertItems{5};

            % Make sure our reaper session is muted and in the start state
            sendOSCMess('mute', app.reaperOSC);
            sendOSCMess('reset', app.reaperOSC);
        end

        % Button pushed function: BeginButton
        function BeginButtonPushed(app, event)
            % Sort panel visibility----------------------------------------
            app.WelcomePanel.Visible = 'off'; % Welcome panel off
            app.PersonalInformationPanel.Visible = 'on'; % Personal info panel on
        end

        % Button pushed function: InstructionsHelpButton
        function InstructionsHelpButtonPushed(app, event)
            % Display the instructions for participant input of personal
            % data in a pop up box.
            msgbox(['Please input your personal information.' ...
                    'For each item, click on the ? button on the right for help on how to answer.']);
        end

        % Button pushed function: IDHelpButton
        function IDHelpButtonPushed(app, event)
            % Display instructions for participant input of ID
            msgbox(['Please make an ID code and input it into the text box.' ...
                    'To make your ID code:' ...
                    'Take the first three letters and numbers from your post code.' ...
                    'Then take the last three numbers from your mobile phone number.' ...
                    'Put them together, with the post code part first, and the phone number part second.']);
        end

        % Button pushed function: NameHelpButton
        function NameHelpButtonPushed(app, event)
            % Display instructions for participant input of Name
            msgbox(['Please input your full name into the text box.'])
        end

        % Button pushed function: AgeHelpButton
        function AgeHelpButtonPushed(app, event)
            % Display instructions for participant input of Age
            msgbox(['Please input your age in years into the text box.'])
        end

        % Button pushed function: GenderHelpButton
        function GenderHelpButtonPushed(app, event)
            % Display instructions for participant input of Gender
            msgbox(['Please input your gender into the text box.'])
        end

        % Button pushed function: EthnicityHelpButton
        function EthnicityHelpButtonPushed(app, event)
            % Display instructions for participant input of Ethnicity
            msgbox(['Please input your ethnicity into the text box.'])
        end

        % Button pushed function: ExperienceHelpButton
        function ExperienceHelpButtonPushed(app, event)
            % Display instructions for participant input of Performance
            % Experience

            msgbox(['Please tick the box which best describes you vocal performance experience.' ...
                "If you have little to no performance experience please select 'Novice.'" ...
                "If you have some vocal performance experience, but have never sung in professional contexts, please select 'Amateur.'" + ...
                "If you have some experience singing in professional contexts, please select 'Semi-Professional.'" + ...
                "If you regularly sing in professional contexts, please select 'Professional.'"])
        end

        % Button pushed function: CommentHelpButton
        function CommentHelpButtonPushed(app, event)
            % Display instructions for participant comment on singing
            % experience
            msgbox(["Please input any additional comments describing your vocal performance experience in the text box."])
        end

        % Value changed function: IDEditField
        function IDEditFieldValueChanged(app, event)
            app.id{1,1} = app.IDEditField.Value; % Update the variable which holds this data
            % Update the state - input has been received
            app.idCheck = 1;
            % Save the data to a worksheet
            savePersonalData(app.id, app.name, app.age, app.gender, app.ethnicity, app.experience, app.expComment);
        end

        % Value changed function: NameEditField
        function NameEditFieldValueChanged(app, event)
            app.name{1, 1} = app.NameEditField.Value; % Update the variable which holds this data
            % Update the state - input has been received
            app.nameCheck = 1;
            % Save the data to worksheet
            savePersonalData(app.id, app.name, app.age, app.gender, app.ethnicity, app.experience, app.expComment);
        end

        % Value changed function: AgeEditField
        function AgeEditFieldValueChanged(app, event)
            app.age = app.AgeEditField.Value; % Update the variable which holds this data
            % Update the state - input has been received
            app.ageCheck = 1;
            % Save the data to worksheet
            savePersonalData(app.id, app.name, app.age, app.gender, app.ethnicity, app.experience, app.expComment);
        end

        % Value changed function: GenderEditField
        function GenderEditFieldValueChanged(app, event)
            app.gender{1,1} = app.GenderEditField.Value; % Update the variable which holds this data
            % Update the state - input has been received
            app.genderCheck = 1;
            % Save the data to worksheet
            savePersonalData(app.id, app.name, app.age, app.gender, app.ethnicity, app.experience, app.expComment);
        end

        % Value changed function: EthnicityEditField
        function EthnicityEditFieldValueChanged(app, event)
            app.ethnicity{1,1} = app.EthnicityEditField.Value; % Update the variable which holds this data
            % Update the state - input has been received
            app.ethnicityCheck = 1;
            % Save the data to worksheet
            savePersonalData(app.id, app.name, app.age, app.gender, app.ethnicity, app.experience, app.expComment);
        end

        % Value changed function: CommentEditField
        function CommentEditFieldValueChanged(app, event)
            app.expComment{1,1} = app.CommentEditField.Value; % Update the variable which holds this data
            % Save the data to worksheet
            savePersonalData(app.id, app.name, app.age, app.gender, app.ethnicity, app.experience, app.expComment);
        end

        % Value changed function: NoviceCheckBox
        function NoviceCheckBoxValueChanged(app, event)
            if app.NoviceCheckBox.Value == 1
                % Reset other checkboxes
                app.AmateurCheckBox.Value = 0;
                app.SemiProfessionalCheckBox.Value = 0;
                app.ProfessionalCheckBox.Value = 0;
                
                % Update experience value
                app.experience = {'Novice'};

                % Update the state - input has been received
                app.experienceCheck = 1;

                % Save data
                savePersonalData(app.id, app.name, app.age, app.gender, app.ethnicity, app.experience, app.expComment);
            end
        end

        % Value changed function: AmateurCheckBox
        function AmateurCheckBoxValueChanged(app, event)
            if app.AmateurCheckBox.Value == 1
                % Reset other checkboxes
                app.NoviceCheckBox.Value = 0;
                app.SemiProfessionalCheckBox.Value = 0;
                app.ProfessionalCheckBox.Value = 0;
                
                % Update experience value
                app.experience = {'Amateur'};

                % Update the state - input has been received
                app.experienceCheck = 1;

                % Save data
                savePersonalData(app.id, app.name, app.age, app.gender, app.ethnicity, app.experience, app.expComment);
            end
            
        end

        % Value changed function: SemiProfessionalCheckBox
        function SemiProfessionalCheckBoxValueChanged(app, event)
            if app.SemiProfessionalCheckBox.Value == 1
                % Reset other checkboxes
                app.NoviceCheckBox.Value = 0;
                app.AmateurCheckBox.Value = 0;
                app.ProfessionalCheckBox.Value = 0;
                
                % Update experience value
                app.experience = {'Semi Professional'};

                % Update the state - input has been received
                app.experienceCheck = 1;

                % Save data
                savePersonalData(app.id, app.name, app.age, app.gender, app.ethnicity, app.experience, app.expComment);
            end
        end

        % Value changed function: ProfessionalCheckBox
        function ProfessionalCheckBoxValueChanged(app, event)
            if app.ProfessionalCheckBox.Value == 1
                % Reset other checkboxes
                app.NoviceCheckBox.Value = 0;
                app.AmateurCheckBox.Value = 0;
                app.SemiProfessionalCheckBox.Value = 0;
                
                % Update experience value
                app.experience = {'Professional'};

                % Update the state - input has been received
                app.experienceCheck = 1;

                % Save data
                savePersonalData(app.id, app.name, app.age, app.gender, app.ethnicity, app.experience, app.expComment);
            end            
        end

        % Button pushed function: InfoReadyButton
        function InfoReadyButtonPushed(app, event)
            % Check that all the required fields have been input
            if app.idCheck == 1 && app.nameCheck == 1 && app.ageCheck == 1 && app.genderCheck == 1 && app.ethnicityCheck == 1 && app.experienceCheck == 1
                % Save data
                savePersonalData(app.id, app.name, app.age, app.gender, app.ethnicity, app.experience, app.expComment);
                % Move to the Concepts Panel
                app.PersonalInformationPanel.Visible = 'off'; % Welcome panel off
                app.ConceptsPanel.Visible = 'on'; % Personal info panel on
            end
            
            % Prompts for fields with no input
            if app.idCheck ~= 1
                msgbox(['Please input your participant ID.'])
            end

            if app.nameCheck ~= 1
                msgbox(['Please input your participant name.'])
            end

            if app.ageCheck ~= 1
                msgbox(['Please input your participant age.'])
            end

            if app.genderCheck ~= 1
                msgbox(['Please input your participant gender.'])
            end

            if app.ethnicityCheck ~= 1
                msgbox(['Please input your participant ethnicity.'])
            end

            if app.experienceCheck ~= 1
                msgbox(['Please input your participant vocal performance experience level.'])
            end
        end

        % Button pushed function: Q1ConceptHelpButton
        function Q1ConceptHelpButtonPushed(app, event)
            % Display message detailing the concept of audio delay
            msgbox(['When you play music over a network there can be a lag between performers' ...
                'This lag describes the time delay between the time at which one musician performs a musical sound and the time at which other musicians hear that sound.' ...
                'When you are asked whether or not the audio delay is noticeable, we want you to rate how noticable you think this lag between musicians is.'])
        end

        % Button pushed function: Q2ConceptHelpButton
        function Q2ConceptHelpButtonPushed(app, event)
            % Display message detailing the concept of playing in time
            msgbox(['When you play music as part of a duet both musicians are intended to play in time together.' ...
                'When we say play in time, we mean that musical notes sound like they are happening when they are supposed to happen.' ...
                'This can mean when both musicians perform a musical event which is supposed to sound at the same time.' ...
                'This can also mean how in time a musician is within a part - such as having the correct beat divisions between notes, and maintaining the intended tempo.' ...
                'When you are asked whether or not you are able to play in time, we want you to rate how in time you feel the performance is both within and between parts.'])
        end

        % Button pushed function: Q3ConceptHelpButton
        function Q3ConceptHelpButtonPushed(app, event)
            % Display message detailing the concept of clarity
            msgbox(['When playing music with others the way you hear other musicians performance can be described in terms of how clear it is.' ...
                'By clear we mean that you hear and distinguish the seperate notes in a musicians performance.' ...
                'A performance may not sound clear if all the seperate notes are heard as blending together to some degree.' ...
                'When we ask you whether or not you can hear the performance clearly, we want you to rate how well you can make out and distinguish the different musical notes performed.'])
        end

        % Button pushed function: Q4ConceptHelpButton
        function Q4ConceptHelpButtonPushed(app, event)
            % Display message detailing the concept of reverberation level
            msgbox(['When sound is made in a room, sound waves reflect around the room.' ...
                'This is called reverberation.' ...
                'The loudness of the reverberation is how loud these reflections are.' ...
                'Loud reverberation may be heard as close to as loud as the original sound and is easy to hear.' ...
                'Quite reverberation may be much less loud than the original sound, and can be hard to hear.' ...
                'When you are asked whether or not you think the reverberation loudness is good or bad, we want you to rate whether or not you think how loud the reverberation is makes for good or bad performance conditions.'])
        end

        % Button pushed function: Q5ConceptHelpButton
        function Q5ConceptHelpButtonPushed(app, event)
            % Display message detailing the concept of reverberation time
            msgbox(['When sound is made in a room, sound waves reflect around the room.' ...
                'This is called reverberation.' ...
                'The time of the reverberation describes how long it takes for these reflections to fade away to silence.' ...
                'In large rooms the sound can take some time to fade to silence.' ...
                'In small rooms the sound can fade to silence quickly.' ...
                'When you are asked whether or not the reverberation time is good to perform in, we want you to rate whether or not you think the time taken for reflections to fade away makes for good or bad performance conditions.'])
        end

        % Button pushed function: ReadyButton
        function ReadyButtonPushed(app, event)
            % Sort panel visibility
            app.ConceptsPanel.Visible = 'off';
            app.PracticePanel.Visible = 'on';

            % Load practice conditions
            sendOSCMess('practice', app.reaperOSC); % set up reaper

            % Notify partner we are ready
            write(app.commsTx, 1, "int8", app.destIP, app.destPort);

            % Wait to be notified from our partner that they are ready
            while app.readyStatus == 0
                nBytes = app.commsRx.NumBytesAvailable; % check if there are bytes available to read
                if nBytes == 0 % If there is nothing to read
                    pause(1); % Keep waiting
                end
                if nBytes ~= 0
                    tempData = read(app.commsRx, nBytes, "int8"); % Read the data
                    currentData = tempData(1, (length(tempData))); % Get the most recent bit of data if there is more than one byte here
                    if currentData == 1 % If this data is our expected 'partner ready' boolean
                        % Sort display
                        app.practiceLamp.Color = 'Green';
                        app.practiceLampLabel.Text = "Ready!";

                        % Unmute Reaper
                        sendOSCMess('unmute', app.reaperOSC);

                        % Clean the socket
                        flush(app.commsRx);

                        % Exit while loop
                        app.readyStatus = 1;
                    elseif currentData ~= 1 % If we have unexpected data here, flag an error
                        error("unexpected data!");
                    end
                end
            end
            app.readyStatus = 0; % reset ready status
        end

        % Button pushed function: practiceReadyButton
        function practiceReadyButtonPushed(app, event)
            % Reset and mute Reaper
            sendOSCMess('reset', app.reaperOSC);

            % Sort Visibility
            app.PracticePanel.Visible = "off";
            app.MainPanel.Visible = "on";

            % begin test sequence
            app.index = 1;

            % Load first conditions from sequence-------------------------
            del = app.delSeq(app.index);
            sys = app.sysSeq(app.index);
            
            % send reaper osc---------------------------------------------
            if del == 1
                sendOSCMess('latency1', app.reaperOSC);
            elseif del == 2
                sendOSCMess('latency2', app.reaperOSC);
            elseif del == 3
                sendOSCMess('latency3', app.reaperOSC);
            end

            if sys == 1
                sendOSCMess('system1', app.reaperOSC);
            elseif sys == 2
                sendOSCMess('system2', app.reaperOSC);
            elseif sys == 3
                sendOSCMess('system3', app.reaperOSC);
            end

            % Status updates----------------------------------------------
            % Notify partner we are ready
            write(app.commsTx, 1, "int8", app.destIP, app.destPort);

            % Wait for our partner to tell us they are ready
            while app.readyStatus == 0
                nBytes = app.commsRx.NumBytesAvailable; % check if there are bytes available to read
                if nBytes == 0 % If there is nothing to read
                    pause(1); % Keep waiting
                end
                if nBytes ~= 0
                    tempData = read(app.commsRx, nBytes, "int8"); % Read the data
                    currentData = tempData(1, (length(tempData))); % Get the most recent bit of data if there is more than one byte here
                    if currentData == 1 % If this data is our expected 'partner ready' boolean
                        % Sort display
                        app.Lamp.Color = 'Green';
                        app.mainLampLabel.Text = "Ready!";

                        % Unmute Reaper
                        sendOSCMess('unmute', app.reaperOSC);

                        % Clean the socket
                        flush(app.commsRx);

                        % Exit while loop
                        app.readyStatus = 1;
                    elseif currentData ~= 1 % If we have unexpected data here, flag an error
                        error("unexpected data!");
                    end
                end
            end
            app.readyStatus = 0; % reset ready status

            % Set all sliders to start on neutral
            app.Q1Slider.Value = 4;
            app.Q2Slider.Value = 4;
            app.Q3Slider.Value = 4;
            app.Q4Slider.Value = 4;
            app.Q5Slider.Value = 4;

            % Make sure all the slider value changed flags are 0
            app.q1changed = 0;
            app.q2changed = 0;
            app.q3changed = 0;
            app.q4changed = 0;
            app.q5changed = 0;
        end

        % Value changing function: Q1Slider
        function Q1SliderValueChanging(app, event)
            app.q1changed = 1;
        end

        % Value changing function: Q2Slider
        function Q2SliderValueChanging(app, event)
            app.q2changed = 1;
        end

        % Value changing function: Q3Slider
        function Q3SliderValueChanging(app, event)
            app.q3changed = 1;
        end

        % Value changing function: Q4Slider
        function Q4SliderValueChanging(app, event)
            app.q4changed = 1;
        end

        % Value changing function: Q5Slider
        function Q5SliderValueChanging(app, event)
            app.q5changed = 1;
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            % Save current likert response--------------------------------
            % Reset reaper
            sendOSCMess('reset', app.reaperOSC);
            
            % Get likert data from sliders
            app.q1(app.index) = app.Q1Slider.Value;
            app.q2(app.index) = app.Q2Slider.Value;
            app.q3(app.index) = app.Q3Slider.Value;
            app.q4(app.index) = app.Q4Slider.Value;
            app.q5(app.index) = app.Q5Slider.Value;

            % Check our values have been changed
            if app.q1changed ~= 1
                msgbox(["Please input a rating for item 1."])
            end
            if app.q2changed ~= 1
                msgbox(["Please input a rating for item 2."])
            end
            if app.q3changed ~= 1
                msgbox(["Please input a rating for item 3."])
            end
            if app.q4changed ~= 1
                msgbox(["Please input a rating for item 4"]);
            end
            if app.q5changed ~= 1
                msgbox(["Please input a rating for item 5"]);
            end

            % If all values have been changed save the likert data and
            % progress to the next set of conditions----------------------

            if app.q1changed == 1 && app.q2changed == 1 && app.q3changed == 1 && app.q4changed == 1 && app.q5changed == 1
                % Save Likert Data
                saveLikertData(app.id, app.repSeq, app.delSeq, app.sysSeq, app.q1, app.q2, app.q3, app.q4, app.q5)

                % Check if we have reached the end of the test
                if app.index + 1 > 27
                    % If we have reached the end, move to the finished
                    % panel
                    app.MainPanel.Visible = "off";
                    app.FinishedPanel.Visible = "on";
                end

                % Otherwise move to the next set of conditions------------
                % Reset partner status lamp and label to wait mode
                if app.index + 1 < 28 % Kind of redundant but good to make sure
                    app.Lamp.Color = "red";
                    app.mainLampLabel.Text = "Waiting for duet partner...";

                    % Reset sliders
                    app.Q1Slider.Value = 4;
                    app.Q2Slider.Value = 4;
                    app.Q3Slider.Value = 4;
                    app.Q4Slider.Value = 4;
                    app.Q5Slider.Value = 4;

                    % Reset Slider changed flags
                    app.q1changed = 0;
                    app.q2changed = 0;
                    app.q3changed = 0;
                    app.q4changed = 0;
                    app.q5changed = 0;

                    % Begin status updates------------------------------------
                    % Notify partner we are ready
                    write(app.commsTx, 1, "int8", app.destIP, app.destPort);

                    % Wait for our partner to tell us they are ready
                    while app.readyStatus == 0
                        nBytes = app.commsRx.NumBytesAvailable; % check if there are bytes available to read
                        if nBytes == 0 % If there is nothing to read
                            pause(1); % Keep waiting
                        end
                        if nBytes ~= 0
                            tempData = read(app.commsRx, nBytes, "int8"); % Read the data
                            currentData = tempData(1, (length(tempData))); % Get the most recent bit of data if there is more than one byte here
                            if currentData == 1 % If this data is our expected 'partner ready' boolean
                                % Increment the sequence index
                                app.index = app.index + 1;
                
                                del = app.delSeq(app.index); % Get delay conditions
                                sys = app.sysSeq(app.index); % Get system conditions
                                % send reaper osc-----------------------------------------
                                if del == 1
                                    sendOSCMess('latency1', app.reaperOSC);
                                elseif del == 2
                                    sendOSCMess('latency2', app.reaperOSC);
                                elseif del == 3
                                    sendOSCMess('latency3', app.reaperOSC);
                                end

                                if sys == 1
                                    sendOSCMess('system1', app.reaperOSC);
                                elseif sys == 2
                                    sendOSCMess('system2', app.reaperOSC);
                                elseif sys == 3
                                    sendOSCMess('system3', app.reaperOSC);
                                end
                                
                                % Sort display
                                app.Lamp.Color = 'Green';
                                app.mainLampLabel.Text = "Ready!";

                                % Unmute Reaper
                                sendOSCMess('unmute', app.reaperOSC);

                                % Clean the socket
                                flush(app.commsRx);

                                % Exit while loop
                                app.readyStatus = 1;
                            elseif currentData ~= 1 % If we have unexpected data here, flag an error
                                error("unexpected data!");
                            end
                        end
                    end
                    app.readyStatus = 0; % reset ready status
                end
            end
        end

        % Value changed function: Q1Slider
        function Q1SliderValueChanged(app, event)
            % Snap to the nearest whole increment
            app.Q1Slider.Value = round(app.Q1Slider.Value);
        end

        % Value changed function: Q2Slider
        function Q2SliderValueChanged(app, event)
            % Snap to the nearest whole increment
            app.Q2Slider.Value = round(app.Q2Slider.Value);
        end

        % Value changed function: Q3Slider
        function Q3SliderValueChanged(app, event)
            app.Q3Slider.Value = round(app.Q3Slider.Value);
        end

        % Value changed function: Q4Slider
        function Q4SliderValueChanged(app, event)
            app.Q4Slider.Value = round(app.Q4Slider.Value);
        end

        % Value changed function: Q5Slider
        function Q5SliderValueChanged(app, event)
            app.Q5Slider.Value = round(app.Q5Slider.Value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.WelcomePanel = uipanel(app.UIFigure);
            app.WelcomePanel.Title = 'Welcome';
            app.IntroLabel = uilabel(app.WelcomePanel);
            app.IntroLabel.Text = {'Welcome!'; ''; 'Thank you for agreeing to participate in this study.'; ''; 'You will first be asked to input your personal data.'; ''; 'You will be then be familiarised with some of the things you will be asked questions about during this study.'; ''; 'You will then be given a short period of time to practice performing with your duet partner.'; ''; 'The study will then begin.'; ''; 'You will be asked to perform a short piece of music with your duet partner.'; 'This will be repeated multiple times.'; 'After each performance, you will be asked to provide rating of your performance experience.'; ''; 'After this is done the study will be completed.'; ''; 'Please click on the ''Begin'' button to get started when you are ready.'; ''; 'If you have any questions, please do not hesitate to ask the researcher.'};
            app.BeginButton = uibutton(app.WelcomePanel, 'push');
            app.BeginButton.ButtonPushedFcn = createCallbackFcn(app, @BeginButtonPushed, true);
            app.BeginButton.Text = 'Begin';
            app.PersonalInformationPanel = uipanel(app.UIFigure);
            app.PersonalInformationPanel.Title = 'Personal Information';
            app.IDHelpButton = uibutton(app.PersonalInformationPanel, 'push');
            app.IDHelpButton.ButtonPushedFcn = createCallbackFcn(app, @IDHelpButtonPushed, true);
            app.IDHelpButton.Text = '?';
            app.NameHelpButton = uibutton(app.PersonalInformationPanel, 'push');
            app.NameHelpButton.ButtonPushedFcn = createCallbackFcn(app, @NameHelpButtonPushed, true);
            app.NameHelpButton.Text = '?';
            app.AgeHelpButton = uibutton(app.PersonalInformationPanel, 'push');
            app.AgeHelpButton.ButtonPushedFcn = createCallbackFcn(app, @AgeHelpButtonPushed, true);
            app.AgeHelpButton.Text = '?';
            app.GenderHelpButton = uibutton(app.PersonalInformationPanel, 'push');
            app.GenderHelpButton.ButtonPushedFcn = createCallbackFcn(app, @GenderHelpButtonPushed, true);
            app.GenderHelpButton.Text = '?';
            app.EthnicityHelpButton = uibutton(app.PersonalInformationPanel, 'push');
            app.EthnicityHelpButton.ButtonPushedFcn = createCallbackFcn(app, @EthnicityHelpButtonPushed, true);
            app.EthnicityHelpButton.Text = '?';
            app.NoviceCheckBox = uicheckbox(app.PersonalInformationPanel);
            app.NoviceCheckBox.ValueChangedFcn = createCallbackFcn(app, @NoviceCheckBoxValueChanged, true);
            app.NoviceCheckBox.Text = 'Novice';
            app.AmateurCheckBox = uicheckbox(app.PersonalInformationPanel);
            app.AmateurCheckBox.ValueChangedFcn = createCallbackFcn(app, @AmateurCheckBoxValueChanged, true);
            app.AmateurCheckBox.Text = 'Amateur';
            app.SemiProfessionalCheckBox = uicheckbox(app.PersonalInformationPanel);
            app.SemiProfessionalCheckBox.ValueChangedFcn = createCallbackFcn(app, @SemiProfessionalCheckBoxValueChanged, true);
            app.SemiProfessionalCheckBox.Text = 'Semi-Professional';
            app.ProfessionalCheckBox = uicheckbox(app.PersonalInformationPanel);
            app.ProfessionalCheckBox.ValueChangedFcn = createCallbackFcn(app, @ProfessionalCheckBoxValueChanged, true);
            app.ProfessionalCheckBox.Text = 'Professional';
            app.SingingExperienceLabel = uilabel(app.PersonalInformationPanel);
            app.SingingExperienceLabel.Text = 'Singing Experience';
            app.CommentHelpButton = uibutton(app.PersonalInformationPanel, 'push');
            app.CommentHelpButton.ButtonPushedFcn = createCallbackFcn(app, @CommentHelpButtonPushed, true);
            app.CommentHelpButton.Text = '?';
            app.ParticipantIDLabel = uilabel(app.PersonalInformationPanel);
            app.ParticipantIDLabel.Text = 'Participant ID';
            app.IDEditField = uieditfield(app.PersonalInformationPanel, 'text');
            app.IDEditField.ValueChangedFcn = createCallbackFcn(app, @IDEditFieldValueChanged, true);
            app.ParticipantIDLabel_2 = uilabel(app.PersonalInformationPanel);
            app.ParticipantIDLabel_2.Text = 'Name';
            app.NameEditField = uieditfield(app.PersonalInformationPanel, 'text');
            app.NameEditField.ValueChangedFcn = createCallbackFcn(app, @NameEditFieldValueChanged, true);
            app.ParticipantIDLabel_4 = uilabel(app.PersonalInformationPanel);
            app.ParticipantIDLabel_4.Text = 'Gender';
            app.GenderEditField = uieditfield(app.PersonalInformationPanel, 'text');
            app.GenderEditField.ValueChangedFcn = createCallbackFcn(app, @GenderEditFieldValueChanged, true);
            app.ParticipantIDLabel_5 = uilabel(app.PersonalInformationPanel);
            app.ParticipantIDLabel_5.Text = 'Ethnicity';
            app.EthnicityEditField = uieditfield(app.PersonalInformationPanel, 'text');
            app.EthnicityEditField.ValueChangedFcn = createCallbackFcn(app, @EthnicityEditFieldValueChanged, true);
            app.ParticipantIDLabel_6 = uilabel(app.PersonalInformationPanel);
            app.ParticipantIDLabel_6.Text = 'Comment';
            app.CommentEditField = uieditfield(app.PersonalInformationPanel, 'text');
            app.CommentEditField.ValueChangedFcn = createCallbackFcn(app, @CommentEditFieldValueChanged, true);
            app.InstructionsLabel = uilabel(app.PersonalInformationPanel);
            app.InstructionsLabel.Text = 'Instructions';
            app.InstructionsHelpButton = uibutton(app.PersonalInformationPanel, 'push');
            app.InstructionsHelpButton.ButtonPushedFcn = createCallbackFcn(app, @InstructionsHelpButtonPushed, true);
            app.InstructionsHelpButton.Text = '?';
            app.ExperienceHelpButton = uibutton(app.PersonalInformationPanel, 'push');
            app.ExperienceHelpButton.ButtonPushedFcn = createCallbackFcn(app, @ExperienceHelpButtonPushed, true);
            app.ExperienceHelpButton.Text = '?';
            app.InfoReadyButton = uibutton(app.PersonalInformationPanel, 'push');
            app.InfoReadyButton.ButtonPushedFcn = createCallbackFcn(app, @InfoReadyButtonPushed, true);
            app.InfoReadyButton.Text = 'Ready';
            app.AgeLabel = uilabel(app.PersonalInformationPanel);
            app.AgeLabel.Text = 'Age ';
            app.AgeEditField = uieditfield(app.PersonalInformationPanel, 'numeric');
            app.AgeEditField.ValueChangedFcn = createCallbackFcn(app, @AgeEditFieldValueChanged, true);
            app.ConceptsPanel = uipanel(app.UIFigure);
            app.ConceptsPanel.Title = 'Concepts';
            app.ConceptLabel = uilabel(app.ConceptsPanel);
            app.ConceptLabel.Text = {'In this study you will be asked questions about your experience singing with your duet partner.'; ''; 'In order to make sure all participants are familiar with the topic of each question, the concepts involved are presented here.'; 'Please click the help box next to each question for a brief explanation.'; ''; 'If you have any questions please do not hesitate to ask the researcher.'; ''; 'Please click ready when you are happy that you understand each question.'};
            app.Q1ConceptLabel = uilabel(app.ConceptsPanel);
            app.Q1ConceptLabel.Text = 'Q1: The delay between myself and duet partner is not noticeable.';
            app.Q2ConceptLabel = uilabel(app.ConceptsPanel);
            app.Q2ConceptLabel.Text = 'Q2: I am able to play in time with my duet partner.';
            app.Q3ConceptLabel = uilabel(app.ConceptsPanel);
            app.Q3ConceptLabel.Text = 'Q3: I can hear my duet partner''s voice clearly.';
            app.Q4ConceptLabel = uilabel(app.ConceptsPanel);
            app.Q4ConceptLabel.Text = 'Q4: The loudness of the reverberation is good for singing in.';
            app.Q5ConceptLabel = uilabel(app.ConceptsPanel);
            app.Q5ConceptLabel.Text = 'Q5: The length of the reverberation is good for singing in.';
            app.Q1ConceptHelpButton = uibutton(app.ConceptsPanel, 'push');
            app.Q1ConceptHelpButton.ButtonPushedFcn = createCallbackFcn(app, @Q1ConceptHelpButtonPushed, true);
            app.Q1ConceptHelpButton.Text = '?';
            app.Q2ConceptHelpButton = uibutton(app.ConceptsPanel, 'push');
            app.Q2ConceptHelpButton.ButtonPushedFcn = createCallbackFcn(app, @Q2ConceptHelpButtonPushed, true);
            app.Q2ConceptHelpButton.Text = '?';
            app.Q3ConceptHelpButton = uibutton(app.ConceptsPanel, 'push');
            app.Q3ConceptHelpButton.ButtonPushedFcn = createCallbackFcn(app, @Q3ConceptHelpButtonPushed, true);
            app.Q3ConceptHelpButton.Text = '?';
            app.Q4ConceptHelpButton = uibutton(app.ConceptsPanel, 'push');
            app.Q4ConceptHelpButton.ButtonPushedFcn = createCallbackFcn(app, @Q4ConceptHelpButtonPushed, true);
            app.Q4ConceptHelpButton.Text = '?';
            app.Q5ConceptHelpButton = uibutton(app.ConceptsPanel, 'push');
            app.Q5ConceptHelpButton.ButtonPushedFcn = createCallbackFcn(app, @Q5ConceptHelpButtonPushed, true);
            app.Q5ConceptHelpButton.Text = '?';
            app.ReadyButton = uibutton(app.ConceptsPanel, 'push');
            app.ReadyButton.ButtonPushedFcn = createCallbackFcn(app, @ReadyButtonPushed, true);
            app.ReadyButton.Text = 'Ready';
            app.PracticePanel = uipanel(app.UIFigure);
            app.PracticePanel.Title = 'Practice';
            app.practiceLabel = uilabel(app.PracticePanel);
            app.practiceLabel.Text = {'Please take some time to practice singing with your duet partner.'; ''; 'When you feel you are ready to begin the study, please click the ''Ready'' button below.'; ''; 'If you have any questions please do not hesitate to ask the researcher.'};
            app.practiceReadyButton = uibutton(app.PracticePanel, 'push');
            app.practiceReadyButton.ButtonPushedFcn = createCallbackFcn(app, @practiceReadyButtonPushed, true);
            app.practiceReadyButton.Text = 'Ready';
            app.practiceLamp = uilamp(app.PracticePanel);
            app.practiceLampLabel = uilabel(app.PracticePanel);
            app.practiceLampLabel.Text = 'Waiting for duet partner...';
            app.MainPanel = uipanel(app.UIFigure);
            app.MainPanel.Title = 'Main';
            app.Q1Label = uilabel(app.MainPanel);
            app.Q1Label.Text = 'Q1: The audio delay between myself and duet partner is not noticeable';
            app.Q1Slider = uislider(app.MainPanel);
            app.Q1Slider.Limits = [1 7];
            app.Q1Slider.MajorTicks = [1 2 3 4 5 6 7];
            app.Q1Slider.MajorTickLabels = {'Strong Disagree', 'Disagree', 'Slight Disagree', 'Neutral', 'Slight Agree', 'Agree', 'Strong Agree'};
            app.Q1Slider.ValueChangedFcn = createCallbackFcn(app, @Q1SliderValueChanged, true);
            app.Q1Slider.ValueChangingFcn = createCallbackFcn(app, @Q1SliderValueChanging, true);
            app.Q1Slider.MinorTicks = [];
            app.Q1Slider.Value = 1;
            app.Q2Label = uilabel(app.MainPanel);
            app.Q2Label.Text = 'Q2: I am able to play in time with my duet partner.';
            app.Q2Slider = uislider(app.MainPanel);
            app.Q2Slider.Limits = [1 7];
            app.Q2Slider.MajorTicks = [1 2 3 4 5 6 7];
            app.Q2Slider.MajorTickLabels = {'Strong Disagree', 'Disagree', 'Slight Disagree', 'Neutral', 'Slight Agree', 'Agree', 'Strong Agree'};
            app.Q2Slider.ValueChangedFcn = createCallbackFcn(app, @Q2SliderValueChanged, true);
            app.Q2Slider.ValueChangingFcn = createCallbackFcn(app, @Q2SliderValueChanging, true);
            app.Q2Slider.MinorTicks = [];
            app.Q2Slider.Value = 1;
            app.Q3Label = uilabel(app.MainPanel);
            app.Q3Label.Text = 'Q3: I can hear my duet partners voice clearly.';
            app.Q3Slider = uislider(app.MainPanel);
            app.Q3Slider.Limits = [1 7];
            app.Q3Slider.MajorTicks = [1 2 3 4 5 6 7];
            app.Q3Slider.MajorTickLabels = {'Strong Disagree', 'Disagree', 'Slight Disagree', 'Neutral', 'Slight Agree', 'Agree', 'Strong Agree'};
            app.Q3Slider.ValueChangedFcn = createCallbackFcn(app, @Q3SliderValueChanged, true);
            app.Q3Slider.ValueChangingFcn = createCallbackFcn(app, @Q3SliderValueChanging, true);
            app.Q3Slider.MinorTicks = [];
            app.Q3Slider.Value = 1;
            app.Q4Label = uilabel(app.MainPanel);
            app.Q4Label.Text = 'Q4: The loudness of the reverberation is good for singing in.';
            app.Q4Slider = uislider(app.MainPanel);
            app.Q4Slider.Limits = [1 7];
            app.Q4Slider.MajorTicks = [1 2 3 4 5 6 7];
            app.Q4Slider.MajorTickLabels = {'Strong Disagree', 'Disagree', 'Slight Disagree', 'Neutral', 'Slight Agree', 'Agree', 'Strong Agree'};
            app.Q4Slider.ValueChangedFcn = createCallbackFcn(app, @Q4SliderValueChanged, true);
            app.Q4Slider.ValueChangingFcn = createCallbackFcn(app, @Q4SliderValueChanging, true);
            app.Q4Slider.MinorTicks = [];
            app.Q4Slider.Value = 1;
            app.Q5Label = uilabel(app.MainPanel);
            app.Q5Label.Text = 'Q5: The length of this reverberation is good for singing in.';
            app.Q5Slider = uislider(app.MainPanel);
            app.Q5Slider.Limits = [1 7];
            app.Q5Slider.MajorTicks = [1 2 3 4 5 6 7];
            app.Q5Slider.MajorTickLabels = {'Strong Disagree', 'Disagree', 'Slight Disagree', 'Neutral', 'Slight Agree', 'Agree', 'Strong Agree'};
            app.Q5Slider.ValueChangedFcn = createCallbackFcn(app, @Q5SliderValueChanged, true);
            app.Q5Slider.ValueChangingFcn = createCallbackFcn(app, @Q5SliderValueChanging, true);
            app.Q5Slider.MinorTicks = [];
            app.Q5Slider.Value = 1;
            app.NextButton = uibutton(app.MainPanel, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.InstructionsButton = uibutton(app.MainPanel, 'push');
            app.InstructionsButton.Text = 'Instructions';
            app.Lamp = uilamp(app.MainPanel);
            app.mainLampLabel = uilabel(app.MainPanel);
            app.mainLampLabel.Text = 'Waiting for duet partner...';
            app.FinishedPanel = uipanel(app.UIFigure);
            app.FinishedPanel.Title = 'Finished';
            app.FinishedLabel = uilabel(app.FinishedPanel);
            app.FinishedLabel.Text = {'The test protocol is now complete.'; ''; 'Thank you for your participation in this study!'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = revisedRTAppv2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end